classdef Parametros < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MsrelevantesButton            matlab.ui.control.Button
        ParametrosdedeteccinButton    matlab.ui.control.Button
        ParametrosdecalidadButton     matlab.ui.control.Button
        ParmetrosdemodeloLabel        matlab.ui.control.Label
        ParmetrosdeclasificacinLabel  matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        PARMETROSLabel                matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Caracteristicas
        Caracteristicas_calidad % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ParametrosdecalidadButton
        function ParametrosdecalidadButtonPushed(app, event)
      app.UITable.Data = app.Caracteristicas_calidad;
        
        end

        % Button pushed function: ParametrosdedeteccinButton
        function ParametrosdedeteccinButtonPushed(app, event)
        app.UITable_2.Data = app.Caracteristicas;
        end

        % Button pushed function: MsrelevantesButton
        function MsrelevantesButtonPushed(app, event)
            Relevantes = Carac_relevantes();
            featureNames = {'Entropia_CR','Entropia_CV','Entropia_CA','Area_Copa','Diametro_Copa_MAX','Diametro_Copa_MIN','Area_Disco','Diametro_Disco_MAX','Diametro_Disco_MIN','C_DRatio','contrast_red','homogeneity_red','correlation_red','energy_red','contrast_green','homogeneity_green','correlation_green','energy_green','contrast_blue','homogeneity_blue','correlation_blue','energy_blue'};

            % Dibuja el gráfico de barras en el UIAxes
            bar(app.UIAxes, Relevantes);
        
            % Establecer etiquetas en el eje x
            set(app.UIAxes, 'XTick', 1:length(featureNames), 'XTickLabel', featureNames);
        
            % Rotar etiquetas si es necesario
            xtickangle(app.UIAxes, 45);
        
            % Agregar títulos y etiquetas de los ejes
            title(app.UIAxes, 'Uso de las caracteristicas en el modelo');
            xlabel(app.UIAxes, 'Características');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Parámetros más relevantes')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'WhatsApp_Image_2024-05-16_at_09.37.41-removebg-preview.png';
            app.PARMETROSLabel = uilabel(app.UIFigure);
            app.PARMETROSLabel.Text = 'PARÁMETROS';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Contraste'; 'Entropia'; 'Laplace'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'''Entropia_CR'''; '''Entropia_CV'''; '''Entropia_CA'''; '''Area_Copa'''; '''Diametro_Copa_MAX'''; '''Diametro_Copa_MIN'''; '''Area_Disco'''; '''Diametro_Disco_MAX'''; '''Diametro_Disco_MIN'''; '''C_DRatio'''; '''contrast_red'''; '''homogeneity_red'''; '''correlation_red'''; '''energy_red'''; '''contrast_green'''; '''homogeneity_green'''; '''correlation_green'''; '''energy_green'''; '''contrast_blue'''; '''homogeneity_blue'''; '''correlation_blue'''; '''energy_blue'''};
            app.UITable_2.RowName = {};
            app.ParmetrosdeclasificacinLabel = uilabel(app.UIFigure);
            app.ParmetrosdeclasificacinLabel.Text = 'Parámetros de clasificación';
            app.ParmetrosdemodeloLabel = uilabel(app.UIFigure);
            app.ParmetrosdemodeloLabel.Text = 'Parámetros de modelo';
            app.ParametrosdecalidadButton = uibutton(app.UIFigure, 'push');
            app.ParametrosdecalidadButton.ButtonPushedFcn = createCallbackFcn(app, @ParametrosdecalidadButtonPushed, true);
            app.ParametrosdecalidadButton.Text = 'Parametros de calidad';
            app.ParametrosdedeteccinButton = uibutton(app.UIFigure, 'push');
            app.ParametrosdedeteccinButton.ButtonPushedFcn = createCallbackFcn(app, @ParametrosdedeteccinButtonPushed, true);
            app.ParametrosdedeteccinButton.Text = 'Parametros de detección';
            app.MsrelevantesButton = uibutton(app.UIFigure, 'push');
            app.MsrelevantesButton.ButtonPushedFcn = createCallbackFcn(app, @MsrelevantesButtonPushed, true);
            app.MsrelevantesButton.Text = 'Más relevantes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parametros

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end