classdef datosdelpaciente < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        TextArea_2                      matlab.ui.control.TextArea
        Image2                          matlab.ui.control.Image
        DATOSDELPACIENTELabel           matlab.ui.control.Label
        VALIDEZDELAIMAGENTextAreaLabel  matlab.ui.control.Label
        VALIDEZDELAIMAGENTextArea       matlab.ui.control.TextArea
        IDdelpacienteEditField_2Label   matlab.ui.control.Label
        IDdelpacienteEditField_2        matlab.ui.control.EditField
        CentrosanitarioEditFieldLabel   matlab.ui.control.Label
        CentrosanitarioEditField        matlab.ui.control.EditField
        FechadeldiagnsticoDatePickerLabel  matlab.ui.control.Label
        FechadeldiagnsticoDatePicker    matlab.ui.control.DatePicker
        EdadEditFieldLabel              matlab.ui.control.Label
        EdadEditField                   matlab.ui.control.NumericEditField
        IDdelordenadorEditFieldLabel    matlab.ui.control.Label
        IDdelordenadorEditField         matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        SiguienteButton                 matlab.ui.control.Button
        CargarimagenButton              matlab.ui.control.Button
        CargardatosButton               matlab.ui.control.Button
        IMAGENLabel                     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public, Transient, NonCopyable)
        ImageData uint8 % Datos de la imagen cargada
        Caracteristicas
        Caracteristicas_calidad
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)

     % Abrir cuadro de diálogo de selección de archivos
     [archivo, carpeta] = uigetfile({'*.jpg'}, 'Seleccionar imagen');
 
        % Construir la ruta completa de la imagen seleccionada
         %rutaImagen = fullfile(carpeta, archivo);
         app.ImageData = imread(fullfile(carpeta, archivo));
         % Mostrar la imagen en un componente UIAxes
         imshow(app.ImageData, 'Parent', app.UIAxes);  
imshow(app.ImageData,'Parent', app.UIAxes);
         % CARGAR LA VALIDEZ DE LA IMAGEN
         app.Caracteristicas_calidad=Carac_calidad(app.ImageData);
         CalidadPred=Calidad(app.Caracteristicas_calidad);
         app.VALIDEZDELAIMAGENTextArea.Value =CalidadPred;

if CalidadPred ~="Buena calidad"
    f = msgbox("Introduzca otra imagen ","Error");
end 
        
        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            segundaApp = resultadosdelpaciente;
            segundaApp.ImageData = app.ImageData;
            segundaApp.Caracteristicas_calidad = app.Caracteristicas_calidad;
            segundaApp.UIFigure.Visible = 'on';
            delete(app.UIFigure);
        end

        % Button pushed function: CargardatosButton
        function CargardatosButtonPushed(app, event)
            % Verificar si todos los campos necesarios están completos
    if ~isempty(app.CentrosanitarioEditField.Value) && ~isempty(app.FechadeldiagnsticoDatePicker.Value) && ~isempty(app.IDdelpacienteEditField_2.Value) && ~isempty(app.EdadEditField.Value) && ~isempty(app.IDdelordenadorEditField.Value) 
        if length(app.IDdelpacienteEditField_2.Value) == 4 && isstrprop(app.IDdelpacienteEditField_2.Value(1), 'upper') && any(isstrprop(app.IDdelpacienteEditField_2.Value(2:4), 'digit')) && length(app.IDdelordenadorEditField.Value) ==3 && any(isstrprop(app.IDdelordenadorEditField.Value,'digit'))
        app.CargarimagenButton.Enable = 'on';
       
        
        else
        % Alguno de los IDs no cumple con el formato, mostrar mensaje de error
        f = msgbox("Los datos son incorrectos ","Error");
       
        app.CargarimagenButton.Enable = 'off';
        end
    else
    % Alguno de los campos está vacío, mostrar mensaje de error
    f = msgbox("Por favor, complete todos los campos ","Incompleto");
   
    app.CargarimagenButton.Enable = 'off';
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.IMAGENLabel = uilabel(app.UIFigure);
            app.IMAGENLabel.Text = 'IMAGEN';
            app.CargardatosButton = uibutton(app.UIFigure, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatosButtonPushed, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Enable = 'off';
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.SiguienteButton = uibutton(app.UIFigure, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IDdelordenadorEditField = uieditfield(app.UIFigure, 'text');
            app.IDdelordenadorEditFieldLabel = uilabel(app.UIFigure);
            app.IDdelordenadorEditFieldLabel.Text = 'ID del ordenador';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.FechadeldiagnsticoDatePicker = uidatepicker(app.UIFigure);
            app.FechadeldiagnsticoDatePickerLabel = uilabel(app.UIFigure);
            app.FechadeldiagnsticoDatePickerLabel.Text = 'Fecha del diagnóstico';
            app.CentrosanitarioEditField = uieditfield(app.UIFigure, 'text');
            app.CentrosanitarioEditFieldLabel = uilabel(app.UIFigure);
            app.CentrosanitarioEditFieldLabel.Text = 'Centro sanitario';
            app.IDdelpacienteEditField_2 = uieditfield(app.UIFigure, 'text');
            app.IDdelpacienteEditField_2Label = uilabel(app.UIFigure);
            app.IDdelpacienteEditField_2Label.Text = 'ID del paciente';
            app.VALIDEZDELAIMAGENTextArea = uitextarea(app.UIFigure);
            app.VALIDEZDELAIMAGENTextAreaLabel = uilabel(app.UIFigure);
            app.VALIDEZDELAIMAGENTextAreaLabel.Text = 'VALIDEZ DE LA IMAGEN:';
            app.DATOSDELPACIENTELabel = uilabel(app.UIFigure);
            app.DATOSDELPACIENTELabel.Text = 'DATOS DEL PACIENTE';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'ICONO.png';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {''; 'FORMULARIO DEL PACIENTE    '};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'WhatsApp_Image_2024-05-16_at_09.37.41-removebg-preview.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = datosdelpaciente

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end